classdef main_view < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        Histogram                    matlab.ui.container.Tab
        DropDownHistogram            matlab.ui.control.DropDown
        Label_4                      matlab.ui.control.Label
        ImgHistogramGray             matlab.ui.control.Image
        Label_3                      matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        IImgHistogramInput           matlab.ui.control.Image
        BtnHistogramEqualization     matlab.ui.control.Button
        ImgHistogramEqualization     matlab.ui.control.Image
        UIAxesGrayHistogramHistgram  matlab.ui.control.UIAxes
        Enhance                      matlab.ui.container.Tab
        BtnEnhanceExp                matlab.ui.control.Button
        SpinnerEnhanceExpa           matlab.ui.control.Spinner
        cLabel_4                     matlab.ui.control.Label
        SpinnerEnhanceExpc           matlab.ui.control.Spinner
        cLabel_3                     matlab.ui.control.Label
        SpinnerEnhanceExpb           matlab.ui.control.Spinner
        cLabel_2                     matlab.ui.control.Label
        gxybccdotfxyalpha1Label      matlab.ui.control.Label
        gxyccdotlogfxy1Label         matlab.ui.control.Label
        gxytanalphacdotfxyLabel      matlab.ui.control.Label
        SpinnerEnhanceLog            matlab.ui.control.Spinner
        cLabel                       matlab.ui.control.Label
        Label_9                      matlab.ui.control.Label
        Label_8                      matlab.ui.control.Label
        ImgEnhanceExp                matlab.ui.control.Image
        ImgEnhanceLog                matlab.ui.control.Image
        SpinnerEnhanceTan            matlab.ui.control.Spinner
        tanSpinnerLabel              matlab.ui.control.Label
        Label_7                      matlab.ui.control.Label
        ImgEnhanceLinear             matlab.ui.control.Image
        ImgEnhanceGray               matlab.ui.control.Image
        Label_6                      matlab.ui.control.Label
        Label_5                      matlab.ui.control.Label
        ImgEnhanceInput              matlab.ui.control.Image
        RotateScale                  matlab.ui.container.Tab
        Label_13                     matlab.ui.control.Label
        ImgRSfit                     matlab.ui.control.Image
        KnobRSrotate                 matlab.ui.control.Knob
        Label_14                     matlab.ui.control.Label
        Label_12                     matlab.ui.control.Label
        ImgRSrotate                  matlab.ui.control.Image
        SliderRSk_y                  matlab.ui.control.Slider
        k_yLabel                     matlab.ui.control.Label
        SliderRSk_x                  matlab.ui.control.Slider
        k_xSliderLabel_2             matlab.ui.control.Label
        LabelRSScalek                matlab.ui.control.Label
        LabelRSScaleSize             matlab.ui.control.Label
        LabelRSInputSize             matlab.ui.control.Label
        Label_11                     matlab.ui.control.Label
        ImgRSScale                   matlab.ui.control.Image
        Label_10                     matlab.ui.control.Label
        ImgRSInput                   matlab.ui.control.Image
        NoiseFilter                  matlab.ui.container.Tab
        Panel_3                      matlab.ui.container.Panel
        NKnobNFmohu                  matlab.ui.control.DiscreteKnob
        NKnobLabel                   matlab.ui.control.Label
        Label_23                     matlab.ui.control.Label
        ImgNFfiltermohu              matlab.ui.control.Image
        Panel                        matlab.ui.container.Panel
        Button_2                     matlab.ui.control.Button
        omegasigma_ssigma_rLabel     matlab.ui.control.Label
        KnobNFsigma_r                matlab.ui.control.Knob
        sigma_rKnobLabel             matlab.ui.control.Label
        KnobNFsigma_s                matlab.ui.control.Knob
        sigma_sKnobLabel             matlab.ui.control.Label
        SliderNFomega                matlab.ui.control.Slider
        omegaLabel                   matlab.ui.control.Label
        Label_21                     matlab.ui.control.Label
        ImgNFfilter_double           matlab.ui.control.Image
        ImgNFnoise                   matlab.ui.control.Image
        Label_16                     matlab.ui.control.Label
        TabGroup2                    matlab.ui.container.TabGroup
        Tab                          matlab.ui.container.Tab
        SliderNFsaltD                matlab.ui.control.Slider
        Label_17                     matlab.ui.control.Label
        Tab_2                        matlab.ui.container.Tab
        BtnNFg                       matlab.ui.control.Button
        SpinnerNFgVar                matlab.ui.control.Spinner
        Label_19                     matlab.ui.control.Label
        SliderNFgm                   matlab.ui.control.Slider
        Label_18                     matlab.ui.control.Label
        Label_15                     matlab.ui.control.Label
        ImgNFInput                   matlab.ui.control.Image
        Edge                         matlab.ui.container.Tab
        Label_26                     matlab.ui.control.Label
        ImgEdge_gray                 matlab.ui.control.Image
        LaplacianLabel               matlab.ui.control.Label
        PrewittLabel                 matlab.ui.control.Label
        SobelLabel                   matlab.ui.control.Label
        RobertLabel                  matlab.ui.control.Label
        imgEdge_sobel                matlab.ui.control.Image
        imgEdge_laplacian            matlab.ui.control.Image
        imgEdge_prewitt              matlab.ui.control.Image
        imgEdge_robert               matlab.ui.control.Image
        SliderEdge_T                 matlab.ui.control.Slider
        Label_25                     matlab.ui.control.Label
        Label_24                     matlab.ui.control.Label
        imgEdgeinput                 matlab.ui.control.Image
        FG                           matlab.ui.container.Tab
        CheckBoxFGreverse            matlab.ui.control.CheckBox
        Label_30                     matlab.ui.control.Label
        ImgFGres                     matlab.ui.control.Image
        Label_29                     matlab.ui.control.Label
        ImgFGtarget                  matlab.ui.control.Image
        TabGroup3                    matlab.ui.container.TabGroup
        Tab_3                        matlab.ui.container.Tab
        BtnFGOstu                    matlab.ui.control.Button
        OtsuLabel                    matlab.ui.control.Label
        LabelFGOstu                  matlab.ui.control.Label
        Tab_4                        matlab.ui.container.Tab
        Label_31                     matlab.ui.control.Label
        LabelFGt                     matlab.ui.control.Label
        KnobFGt                      matlab.ui.control.Knob
        ThresholdLabel               matlab.ui.control.Label
        kTab                         matlab.ui.container.Tab
        Button                       matlab.ui.control.Button
        SpinnerFGk                   matlab.ui.control.Spinner
        KSpinnerLabel                matlab.ui.control.Label
        Tab_5                        matlab.ui.container.Tab
        KnobFGseed                   matlab.ui.control.Knob
        ThresholdLabel_2             matlab.ui.control.Label
        Btnseed                      matlab.ui.control.Button
        Tab_6                        matlab.ui.container.Tab
        Btnactive                    matlab.ui.control.Button
        Label_28                     matlab.ui.control.Label
        ImgFGbin                     matlab.ui.control.Image
        Label_27                     matlab.ui.control.Label
        ImgFGinput                   matlab.ui.control.Image
        Tab_7                        matlab.ui.container.Tab
        Image_3                      matlab.ui.control.Image
        Image                        matlab.ui.control.Image
        Original                     matlab.ui.control.Image
        SpinnerNFgVar_6              matlab.ui.control.Spinner
        Label_36                     matlab.ui.control.Label
        SpinnerNFgVar_5              matlab.ui.control.Spinner
        Label_35                     matlab.ui.control.Label
        SpinnerNFgVar_4              matlab.ui.control.Spinner
        Label_34                     matlab.ui.control.Label
        HOGButton                    matlab.ui.control.Button
        SpinnerNFgVar_3              matlab.ui.control.Spinner
        Label_33                     matlab.ui.control.Label
        SpinnerNFgVar_2              matlab.ui.control.Spinner
        Label_32                     matlab.ui.control.Label
        LBPButton                    matlab.ui.control.Button
    end


    properties (Access = public)
        FGreverse = false % Description
    end

    methods (Access = public)


        % 统计图像灰度直方图
        function histogram = gray_histogram(app, image)

            histogram = zeros(256, 1);
            [height, width] = size(image);

            for x = 1:width
                for y = 1:height
                    pixelValue = image(y, x) + 1;
                    histogram(pixelValue) = histogram(pixelValue) + 1;
                end
            end
        end


        % 绘制灰度直方图数组到坐标轴
        function draw_histogram(app, histogram)
            % 获取灰度直方图坐标轴组件
            axesHandle = app.UIAxesGrayHistogramHistgram;

            % 绘制直方图
            bar(axesHandle, histogram);

        end
        
         function [histogram, lbp_image] = lbp_histogram(app,image, radius, neighbors)
            % 计算LBP特征直方图
            [height, width] = size(image);
            lbp_image = zeros(height, width);  % 初始化LBP图像
            histogram = zeros(2^neighbors, 1);  % LBP模式的总数是 2^neighbors
        
            for x = 1 + radius : width - radius
                for y = 1 + radius : height - radius
                    % 获取邻域像素
                    neighborhood = getNeighborhood(app,image, x, y, radius, neighbors);
                    % 计算LBP值
                    lbp_value = calculateLBP(app,neighborhood);
                    % 计算直方图
                    lbp_image(y, x) = lbp_value;
                    histogram(lbp_value + 1) = histogram(lbp_value + 1) + 1;
                end
            end
        end
        
        function neighborhood = getNeighborhood(~,image, x, y, radius, neighbors)
            % 获取邻域的像素值
            angle_step = 2 * pi / neighbors;
            neighborhood = zeros(1, neighbors);
            
            for i = 1:neighbors
                angle = angle_step * (i - 1);
                offset_x = round(radius * cos(angle));
                offset_y = round(radius * sin(angle));
                neighborhood(i) = image(y + offset_y, x + offset_x);
            end
        end
        
        function lbp_value = calculateLBP(~,neighborhood)
            % 计算LBP值，返回一个整数值
            center_pixel = neighborhood(1);
            lbp_value = 0;
        
            for i = 2:length(neighborhood)
                if neighborhood(i) >= center_pixel
                    lbp_value = lbp_value + 2^(i - 2);
                end
            end
        end


        % 计算HOG特征
        function [hog_features, hog_image] = hog_feature(app,image, cell_size, block_size, bins)
        
            % 将输入图像转换为双精度
            image = double(image);
            
            % 计算梯度（水平和垂直方向）
            [Gx, Gy] = imgradientxy(image);
            
            % 计算每个像素的梯度幅度和方向
            [mag, ang] = imgradient(Gx, Gy);
            
            % 将图像分成小块（cell）
            [height, width] = size(image);
            num_cells_x = floor(width / cell_size);
            num_cells_y = floor(height / cell_size);
            
            % 初始化HOG图像和特征向量
            hog_image = zeros(height, width);
            hog_features = [];
            
            % 计算每个cell的梯度直方图
            for i = 1:num_cells_y
                for j = 1:num_cells_x
                    % 获取cell区域
                    cell_x_start = (j-1) * cell_size + 1;
                    cell_x_end = j * cell_size;
                    cell_y_start = (i-1) * cell_size + 1;
                    cell_y_end = i * cell_size;
                    
                    % 获取cell内的梯度方向和幅度
                    cell_mag = mag(cell_y_start:cell_y_end, cell_x_start:cell_x_end);
                    cell_ang = ang(cell_y_start:cell_y_end, cell_x_start:cell_x_end);
                    
                    % 计算方向梯度直方图
                    hog_hist = compute_histogram(app,cell_mag, cell_ang, bins);
                    
                    % 将每个cell的HOG特征存储到特征向量中
                    hog_features = [hog_features, hog_hist];
                    
                    % 可视化：构建HOG图像
                    for x = 1:cell_size
                        for y = 1:cell_size
                            angle = cell_ang(y, x);
                            magnitude = cell_mag(y, x);
                            % 将梯度可视化为箭头
                            hog_image(cell_y_start + y - 1, cell_x_start + x - 1) = magnitude;
                        end
                    end
                end
            end
            
            % 归一化HOG特征
            hog_features = normalize_hog(app,hog_features, block_size, num_cells_x, num_cells_y);
        
        end
        % 计算方向梯度直方图
        function hist = compute_histogram(~,mag, ang, bins)
            % 将角度转换到0-180度区间
            ang = mod(ang, 180);
            
            % 创建方向梯度直方图
            hist = zeros(1, bins);
            
            % 计算每个像素的梯度方向对应的bin
            for i = 1:numel(ang)
                bin = floor(ang(i) / (180 / bins)) + 1;
                hist(bin) = hist(bin) + mag(i);
            end
        end

         % 归一化HOG特征
        function hog_features = normalize_hog(~,hog_features, block_size, num_cells_x, num_cells_y)
            % 归一化块（block）
            num_blocks_x = num_cells_x - block_size + 1;
            num_blocks_y = num_cells_y - block_size + 1;
            normalized_hog_features = [];
            
            for i = 1:num_blocks_y
                for j = 1:num_blocks_x
                    block_start_idx = (i-1) * num_cells_x + j;
                    block_end_idx = block_start_idx + block_size * block_size - 1;
                    block = hog_features(block_start_idx:block_end_idx);
                    
                    % L2 归一化
                    norm_factor = norm(block, 2);
                    if norm_factor > 0
                        block = block / norm_factor;
                    end
                    
                    normalized_hog_features = [normalized_hog_features, block];
                end
            end
            
            hog_features = normalized_hog_features;
        end
        

        % 直方图均衡化
        function equalized_image  = histogram_equalization(~, image)

            histgram = imhist(image);
            [h, w] = size(image);
            equalized_image = zeros(h, w, 'uint8'); % 初始化为uint8图像
            s = zeros(256); s(1) = histgram(1);

            for t = 2 : 256
                s(t) = s(t - 1) + histgram(t);
            end

            for x = 1: w
                for y = 1: h
                    equalized_image(y, x) = uint8(255 * s(image(y, x) + 1) / (w * h));

                end
            end
            equalized_image = uint8(equalized_image);

        end

        % 线性灰度级增强
        function new_image = linear_enhance(~, image, tan_alpha)
            % 对每个像素应用线性变换
            new_image = tan_alpha * double(image);
            new_image = uint8(max(0, min(255, new_image)));
        end

        % 非线性对数变换
        function new_image = log_enhance(~, image, c)
            new_image = c * log(1 + double(image));
            new_image = uint8(max(0, min(255, new_image)));
        end


        % 非线性指数变换
        function new_image = exp_enhance(~, image, a, b, c)
            new_image = power(b, c * (image - a)) - 1;
            new_image = uint8(max(0, min(255, new_image)));
        end

        % 图像缩放
        function new_image = scale(~, image, k_x, k_y)
            [h, w, ~] = size(image);
            new_h = round(h * k_y); % 更改画布大小
            new_w = round(w * k_x);

            new_image = zeros(new_h, new_w, 3);  % Initialize the output image with 3 channels for RGB.

            for x_ = 1:new_w
                for y_ = 1:new_h
                    x = x_ / k_x;
                    y = y_ / k_y;
                    a = x - floor(x);
                    b = y - floor(y);
                    x = floor(x);
                    y = floor(y);

                    x = max(1, min(w - 1, x));
                    y = max(1, min(h - 1, y));

                    f_x_y_b = image(y, x, :) + b * (image(y + 1, x, :) - image(y, x, :));
                    f_x_1_y_b = image(y, x + 1, :) + b * (image(y + 1, x + 1, :) - image(y, x + 1, :));
                    f_x_a_y_b = f_x_y_b + a * (f_x_1_y_b - f_x_y_b);
                    new_image(y_, x_, :) = f_x_a_y_b;
                end
            end

            new_image = uint8(new_image);  % 转换为 uint8 类型以便正确显示
        end

        % 图像旋转
        function rotated_image = rotate(~, image, theta)
            [h, w, ~] = size(image);

            % 计算旋转后的图像大小，round四舍五入确保是整数
            new_h = round(h * abs(cosd(theta)) + w * abs(sind(theta)));
            new_w = round(h * abs(sind(theta)) + w * abs(cosd(theta)));

            rotated_image = zeros(new_h, new_w, 3);

            % 计算旋转中心
            center_x = w / 2;
            center_y = h / 2;

            for x_ = 1:new_w
                for y_ = 1:new_h
                    x = x_ - center_x;
                    y = y_ - center_y;

                    % 根据逆变换公式，计算旋转后的坐标
                    % 这是在原图中的位置
                    new_x = cosd(theta) * x - sind(theta) * y + center_x;
                    new_y = sind(theta) * x + cosd(theta) * y + center_y;

                    % 使用双线性插值估算颜色值
                    a = new_x - floor(new_x);
                    b = new_y - floor(new_y);
                    x1 = max(1, min(w - 1, floor(new_x)));
                    y1 = max(1, min(h - 1, floor(new_y)));
                    x2 = min(x1 + 1, w);
                    y2 = min(y1 + 1, h);

                    f_x_y_b = image(y1, x1, :) + b * (image(y2, x1, :) - image(y1, x1, :));
                    f_x_1_y_b = image(y1, x2, :) + b * (image(y2, x2, :) - image(y1, x2, :));
                    f_x_a_y_b = f_x_y_b + a * (f_x_1_y_b - f_x_y_b);
                    rotated_image(y_, x_, :) = f_x_a_y_b;
                end
            end

            rotated_image = uint8(rotated_image);
        end

        % 双边滤波
        function filter_image = filter_double(~, image, w, sigma_s, sigma_r)
            image = im2double(image);


            [X, Y] = meshgrid(-w:w, -w:w);

            % 计算邻域内的空间权值
            Gs = exp(-(X.^2 + Y.^2) / (2 * sigma_s^2));


            dim = size(image);
            filter_image = zeros(dim);

            for i = 1:dim(1)
                for j = 1:dim(2)
                    % 确定作用区域
                    iMin = max(i - w, 1);
                    iMax = min(i + w, dim(1));
                    jMin = max(j - w, 1);
                    jMax = min(j + w, dim(2));

                    I = image(iMin:iMax, jMin:jMax, :); % 提取该区域的源图像值赋给 I

                    % 计算灰度临近权值
                    Gr = exp(-(sum((I - image(i, j, :)).^2, 3)) / (2 * sigma_r^2));

                    % W 为Gr 和 G的乘积
                    W = Gr .* Gs((iMin:iMax) - i + w + 1, (jMin:jMax) - j + w + 1);


                    filter_image(i, j, :) = sum(sum(W .* I, 1), 2) / sum(W(:));
                end
            end
        end


        % 基于模糊技术的加权均值滤波
        function filter_image = filter_mohu(~, image, N)
            % N = N;
            [m, n] = size(image);
            image = double(image);
            filter_image = image;
            % 计算权重的分母
            sNum = N^2 - 1;

            % 计算卷积核的半径
            N = (N - 1) / 2;

            % 遍历图像的像素
            for i = 1 + N : m - N
                for j = 1 + N : n - N
                    sum = 0;

                    % 计算局部像素差的平方和
                    for m = i - N : i + N
                        for n = j - N : j + N
                            d = (image(i, j) - image(m, n))^2;
                            sum = sum + d;
                        end
                    end

                    % 计算模糊参数 beta
                    beta = sum / sNum;
                    sum2 = 0;
                    sum3 = 0;

                    % 应用加权均值滤波
                    for m = i - N : i + N
                        for n = j - N : j + N
                            d = (image(i, j) - image(m, n))^2;
                            % 计算权重
                            mu = exp(-d / beta);
                            % 加权均值的分子
                            sum2 = sum2 + mu / beta * image(m, n);
                            % 加权均值的分母
                            sum3 = sum3 + mu / beta;
                        end
                    end

                    % 更新结果图像的像素值
                    filter_image(i, j) = sum2 / sum3;
                end
            end

            % 将结果图像转换为8位无符号整数型
            filter_image = uint8(filter_image);
        end


        % 边缘检测，得到二值边界图像（或者拉普拉斯滤波）
        function edgeImage = get_edge(~, image, op)
            image = im2double(rgb2gray(image));
            if strcmp(op, 'laplacian')
                edgeImage = abs(imfilter(image, fspecial('laplacian', 0)));
            else
                edgeImage = edge(image, op);    % 使用roberts算子进行边缘检测，得到二值边界图像
            end
        end


        % 图像锐化
        function sharpImage = sharpen_image(~, image, op)
            image = im2double(image);

            if strcmp(op, 'roberts')
                H1 = [1 0; 0 -1];
                H2 = [0 1; -1 0]; % roberts算子模板
            elseif strcmp(op, 'sobel')
                H1 = [-1 -2 -1; 0 0 0; 1 2 1];
                H2 = [-1 0 1; -2 0 2; -1 0 1]; % sobel算子模板
            elseif strcmp(op, 'prewitt')
                H1 = [-1 -1 -1; 0 0 0; 1 1 1];
                H2 = [-1 0 1; -1 0 1; -1 0 1]; % prewitt算子模板
            elseif strcmp(op, 'laplacian')
                %H = fspecial('laplacian', 0);   % 生成Laplacian模板
                H = [0 -1 0; -1 5 -1; 0 -1 0];  % Laplacian 锐化模板
                sharpImage = imfilter(image, H);
                return
            end

            R1 = imfilter(image, H1);
            R2 = imfilter(image, H2);
            edgeImage = abs(R1) + abs(R2); % 基于模板运算，得到梯度图像
            sharpImage = image + edgeImage; % 锐化图像
        end

        function new_image = edge_roberts(~, f, T)

            [m, n] = size(f);
            new_image = zeros(m, n);

            for i = 2:m-1
                for j = 2:n-1
                    new_image(i,j)=abs(f(i+1,j+1)-f(i,j))+abs(f(i,j+1)-f(i+1,j));
                    if new_image(i,j)<T
                        new_image(i,j)=0;
                    else
                        new_image(i,j)=255;
                    end
                end
            end
            new_image = uint8(new_image);
        end

        function new_image = edge_prewitt(~, f, T)
            [m, n] = size(f);
            new_image = zeros(m, n);

            for i = 2:m-1
                for j = 2:n-1

                    new_image(i,j)=abs(f(i-1,j-1)+f(i,j-1)+f(i+1,j-1)-f(i-1,j+1)-f(i,j+1)-f(i+1,j+1))+abs(f(i+1,j-1)+f(i+1,j)+f(i+1,j+1)-f(i-1,j-1)-f(i-1,j)-f(i-1,j+1));

                    if new_image(i, j) < T
                        new_image(i, j) = 0;
                    else
                        new_image(i, j) = 255;
                    end
                end
            end
            new_image = uint8(new_image);
        end

        function new_image = edge_sobel(~, f, T)
            [m, n] = size(f);
            new_image = zeros(m, n);

            for i = 2:m-1
                for j = 2:n-1
                    new_image(i,j)=abs(f(i-1,j-1)+2*f(i,j-1)+f(i+1,j-1)-f(i-1,j+1)-2*f(i,j+1)-f(i+1,j+1))+abs(f(i+1,j-1)+2*f(i+1,j)+f(i+1,j+1)-f(i-1,j-1)-2*f(i-1,j)-f(i-1,j+1));
                    if new_image(i, j) < T
                        new_image(i, j) = 0;
                    else
                        new_image(i, j) = 255;
                    end
                end
            end
            new_image = uint8(new_image);
        end

        function new_image = edge_laplacian(~, f, T)
            [m, n] = size(f);
            new_image = zeros(m, n);

            for i = 2:m-1
                for j = 2:n-1
                    new_image(i,j) = 4 * f(i, j) - f(i-1, j) - f(i+1, j) - f(i, j-1) - f(i, j+1);

                    if new_image(i, j) < T
                        new_image(i, j) = 0;
                    else
                        new_image(i, j) = 255;
                    end
                end
            end
            new_image = uint8(new_image);
        end


        function targetMask = clusterImage(~, image, K)


            hsv = rgb2hsv(image);


            h = hsv(:, :, 1);

            h(h > 330 / 360) = 0;

            training = h(:);


            startdata = linspace(0, 1, K)';


            [IDX, ~] = kmeans(training, K, 'Start', startdata);


            targetMask = reshape(IDX == 1, size(h));

        end


        function segmentedMask = interactiveRegionGrowing(app, image)


            % Convert the image to grayscale
            grayImage = rgb2gray(image);

            % Display the original image for seed selection
            figure;
            imshow(image);
            title('Select seed region for interactive region growing');

            % Allow the user to interactively select seed region
            h = imfreehand;
            mask = createMask(h);

            % Perform region growing
            segmentedMask = regionGrowing(app, grayImage, mask);

        end

        function segmentedMask = regionGrowing(app, image, seedMask)

            threshold = app.KnobFGseed.Value; 


            segmentedMask = zeros(size(image));


            [rows, cols] = find(seedMask);
            seedPoints = [rows, cols];


            for i = 1:size(seedPoints, 1)

                currentSeed = seedPoints(i, :);


                if segmentedMask(currentSeed(1), currentSeed(2)) == 0
                 
                    segmentedMask = growRegion(app, image, segmentedMask, currentSeed, threshold);
                end
            end
        end

        function segmentedMask = growRegion(app, image, segmentedMask, seedPoint, threshold)
         
            queueSize = numel(image);
            queue = zeros(2, queueSize);
            queueHead = 1;
            queueTail = 1;


            [height, width] = size(image);

         
            queue(:, queueTail) = seedPoint';
            queueTail = queueTail + 1;


            neighbors = [-1, 0; 0, -1; 0, 1; 1, 0];

            while queueHead < queueTail

                currentPixel = queue(:, queueHead);
                queueHead = queueHead + 1;

 
                if currentPixel(1) >= 1 && currentPixel(1) <= height && currentPixel(2) >= 1 && currentPixel(2) <= width
                  
                    if segmentedMask(currentPixel(1), currentPixel(2)) == 0
  
                        if abs(image(currentPixel(1), currentPixel(2)) - image(seedPoint(1), seedPoint(2))) <= threshold

                            segmentedMask(currentPixel(1), currentPixel(2)) = 1;

                         
                            for i = 1:size(neighbors, 1)
                                neighbor = currentPixel + neighbors(i, :)';

                            
                                if neighbor(1) >= 1 && neighbor(1) <= height && neighbor(2) >= 1 && neighbor(2) <= width
                                    queue(:, queueTail) = neighbor;
                                    queueTail = queueTail + 1;
                                end
                            end
                        end
                    end
                end
            end
        end


        % 将带有alpha通道的图像放到背景上作为前景
        function blend_img = blend(app, alpha_img, alpha, background_img)
            % 调整 alpha_img 的大小
            targetWidth = size(background_img, 2);
            targetHeight = size(background_img, 1);
            blend_img = imresize(alpha_img, [targetHeight, targetWidth], 'Method', 'bilinear');

            % 获取调整大小后的 alpha 通道
            resizedAlpha = imresize(alpha, [targetHeight, targetWidth], 'Method', 'bilinear');

            % 处理透明通道
            for i = 1:targetHeight
                for j = 1:targetWidth
                    if resizedAlpha(i, j) == 0
                        % 如果当前像素是透明的，将其替换为 app.ImgFGinput.ImageSource 中的对应像素
                        blend_img(i, j, :) = app.ImgFGinput.ImageSource(i, j, :);
                    end
                end
            end

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: IImgHistogramInput
        function ImgHistogramInputOnClicked(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'}, '选择图像文件');
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            end
            fullFilePath = fullfile(pathname, filename);
            imgData = imread(fullFilePath);
            app.IImgHistogramInput.ImageSource = imgData;
            gray_image = rgb2gray(imgData);
            app.ImgHistogramGray.ImageSource =  cat(3, gray_image, gray_image, gray_image);
            histogram = gray_histogram(app, rgb2gray(app.IImgHistogramInput.ImageSource));
            draw_histogram(app, histogram);
        end

        % Button pushed function: BtnHistogramEqualization
        function BtnHistogramEqualizationPushed(app, event)
            if ~isempty(app.IImgHistogramInput.ImageSource)

                equalized_image = histogram_equalization(app, rgb2gray(app.IImgHistogramInput.ImageSource));
                app.ImgHistogramEqualization.ImageSource = cat(3, equalized_image, equalized_image, equalized_image);
            else

                disp('输入图像为空');
            end
        end

        % Value changed function: DropDownHistogram
        function DropDownHistogramValueChanged(app, event)
            value = app.DropDownHistogram.Value;

            if strcmp(value, '原图')
                histogram = gray_histogram(app, rgb2gray(app.IImgHistogramInput.ImageSource));
                draw_histogram(app, histogram);
            elseif strcmp(value, '均衡化')
                histogram = gray_histogram(app, app.ImgHistogramEqualization.ImageSource);
                draw_histogram(app, histogram);
            end
        end

        % Image clicked function: ImgEnhanceInput
        function ImgEnhanceInputClicked(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'}, '选择图像文件');
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            end
            fullFilePath = fullfile(pathname, filename);
            imgData = imread(fullFilePath);
            app.ImgEnhanceInput.ImageSource = imgData;
            gray_image = rgb2gray(imgData);
            app.ImgEnhanceGray.ImageSource =  cat(3, gray_image, gray_image, gray_image);

            new_image_linear = linear_enhance(app, rgb2gray(app.ImgEnhanceGray.ImageSource), app.SpinnerEnhanceTan.Value);
            new_image_log = log_enhance(app, rgb2gray(app.ImgEnhanceGray.ImageSource), app.SpinnerEnhanceLog.Value);

            app.ImgEnhanceLinear.ImageSource = cat(3, new_image_linear, new_image_linear, new_image_linear);
            app.ImgEnhanceLog.ImageSource = cat(3, new_image_log, new_image_log, new_image_log);


        end

        % Value changed function: SpinnerEnhanceTan
        function SpinnerEnhanceTanValueChanged(app, event)
            value = app.SpinnerEnhanceTan.Value;
            new_image = linear_enhance(app, rgb2gray(app.ImgEnhanceGray.ImageSource), value);
            app.ImgEnhanceLinear.ImageSource = cat(3, new_image, new_image, new_image);
        end

        % Value changed function: SpinnerEnhanceLog
        function SpinnerEnhanceLogValueChanged(app, event)
            value = app.SpinnerEnhanceLog.Value;
            new_image = log_enhance(app, rgb2gray(app.ImgEnhanceGray.ImageSource), value);
            app.ImgEnhanceLog.ImageSource = cat(3, new_image, new_image, new_image);
        end

        % Callback function: BtnEnhanceExp, UIAxesGrayHistogramHistgram
        function BtnEnhanceExpButtonPushed(app, event)
            a = app.SpinnerEnhanceExpa.Value;
            b = app.SpinnerEnhanceExpb.Value;
            c = app.SpinnerEnhanceExpc.Value;
            new_image = exp_enhance(app, rgb2gray(app.ImgEnhanceGray.ImageSource), a, b, c);
            app.ImgEnhanceExp.ImageSource = cat(3, new_image, new_image, new_image);
        end

        % Image clicked function: ImgRSInput
        function ImgRSInputImageClicked(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'}, '选择图像文件');
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            end
            fullFilePath = fullfile(pathname, filename);
            imgData = imread(fullFilePath);
            app.ImgRSInput.ImageSource = imgData;
            app.LabelRSInputSize.Text = "图片尺寸：$ " + size(imgData, 2)+ " \times " + size(imgData, 1) + "$";
            app.ImgRSfit.ImageSource = imgData;

        end

        % Value changed function: SliderRSk_x, SliderRSk_y
        function SliderRSValueChanged(app, event)
            imgData = app.ImgRSInput.ImageSource;
            k_x = app.SliderRSk_x.Value;
            k_y = app.SliderRSk_y.Value;
            new_image = scale(app, imgData, k_x, k_y);

            app.ImgRSScale.ImageSource = new_image;
            % 格式化缩放倍数文本
            scaleText = sprintf('%.2f \\times %.2f', k_x, k_y);
            app.LabelRSScalek.Text = "缩放倍数：$ " + scaleText + " $ ";
            % app.LabelRSInputSize_2.Text = "缩放后尺寸：$ " + int32(size(imgData, 2)  * k_x ) + " \times " + int32(size(imgData, 1) * k_y) + "$";

        end

        % Value changed function: KnobRSrotate
        function KnobRSrotateValueChanged(app, event)
            value = app.KnobRSrotate.Value;
            new_image = rotate(app, app.ImgRSScale.ImageSource, value);
            app.ImgRSrotate.ImageSource = new_image;
        end

        % Image clicked function: ImgNFInput
        function ImgNFInputImageClicked(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'}, '选择图像文件');
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            end
            fullFilePath = fullfile(pathname, filename);
            imgData = imread(fullFilePath);
            app.ImgNFInput.ImageSource = imgData;

        end

        % Value changed function: SliderNFsaltD
        function SliderNFsaltDValueChanged(app, event)
            value = app.SliderNFsaltD.Value;
            app.ImgNFnoise.ImageSource =  imnoise(app.ImgNFInput.ImageSource, 'salt & pepper', value);
        end

        % Button pushed function: BtnNFg
        function BtnNFgButtonPushed(app, event)
            m = app.SliderNFgm.Value;    % 均值
            Var = app.SpinnerNFgVar.Value;   % 方差
            app.ImgNFnoise.ImageSource = imnoise(app.ImgNFInput.ImageSource,'gaussian',m, Var);
        end

        % Image clicked function: ImgNFfilter_double
        function ImgNFfilter_doubleImageClicked(app, event)
            w = round(app.SliderNFomega.Value);
            simga_s = double(app.KnobNFsigma_s.Value);
            simga_r = double(app.KnobNFsigma_r.Value);

            app.ImgNFfilter_double.ImageSource =filter_double(app, app.ImgNFnoise.ImageSource, w, simga_s, simga_r);
        end

        % Callback function: ImgNFfiltermohu, NKnobNFmohu
        function NKnobNFmohuValueChanged(app, event)
            value = round(app.NKnobNFmohu.Value) - 48 ;
            app.ImgNFfiltermohu.ImageSource = filter_mohu(app, app.ImgNFnoise.ImageSource, value);

        end

        % Image clicked function: imgEdgeinput
        function imgEdgeinputImageClicked(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'}, '选择图像文件');
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            end
            fullFilePath = fullfile(pathname, filename);
            imgData = imread(fullFilePath);

            app.imgEdgeinput.ImageSource = imgData;


            imgData = rgb2gray(imgData);
            T = app.SliderEdge_T.Value;

            sobel = edge_sobel(app, imgData, T);
            robert = edge_roberts(app, imgData, T);
            laplacian = edge_laplacian(app, imgData, T);
            prewitt = edge_prewitt(app, imgData, T);

            app.ImgEdge_gray.ImageSource = cat(3, imgData, imgData, imgData);
            app.imgEdge_sobel.ImageSource = cat(3, sobel, sobel, sobel);
            app.imgEdge_robert.ImageSource = cat(3, robert, robert, robert);
            app.imgEdge_prewitt.ImageSource = cat(3, prewitt, prewitt, prewitt);
            app.imgEdge_laplacian.ImageSource = cat(3, laplacian, laplacian, laplacian);
        end

        % Value changed function: SliderEdge_T
        function SliderEdge_TValueChanged(app, event)

            imgData = rgb2gray(app.ImgEdge_gray.ImageSource);

            T = round(app.SliderEdge_T.Value);

            sobel = edge_sobel(app, imgData, T);
            robert = edge_roberts(app, imgData, T);
            laplacian = edge_laplacian(app, imgData, T);
            prewitt = edge_prewitt(app, imgData, T);


            app.imgEdge_sobel.ImageSource = cat(3, sobel, sobel, sobel);
            app.imgEdge_robert.ImageSource = cat(3, robert, robert, robert);
            app.imgEdge_prewitt.ImageSource = cat(3, prewitt, prewitt, prewitt);
            app.imgEdge_laplacian.ImageSource = cat(3, laplacian, laplacian, laplacian);
        end

        % Image clicked function: ImgFGinput
        function ImgFGinputImageClicked(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'}, '选择图像文件');
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            end
            fullFilePath = fullfile(pathname, filename);
            imgData = imread(fullFilePath);

            app.ImgFGinput.ImageSource = imgData;

        end

        % Image clicked function: ImgFGtarget
        function ImgFGtargetImageClicked(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'}, '选择图像文件');
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            end

            fullFilePath = fullfile(pathname, filename);
            [imgData, ~, alpha] = imread(fullFilePath);

            % 调整 imgData 的大小
            %             targetWidth = size(app.ImgFGinput.ImageSource, 2);
            %             targetHeight = size(app.ImgFGinput.ImageSource, 1);

            resizedImgData = blend(app, imgData, alpha, app.ImgFGinput.ImageSource);
%             %resizedImgData = histogram_equalization(app, rgb2gray(resizedImgData));
% 
%             resizedImgData = cat(3, resizedImgData, resizedImgData, resizedImgData);
            % 显示结果
            app.ImgFGinput.ImageSource = resizedImgData;

            app.ImgFGtarget.ImageSource = imgData;

        end

        % Button pushed function: BtnFGOstu
        function BtnFGOstuButtonPushed(app, event)
            % 转换为灰度图像
            imgData = app.ImgFGinput.ImageSource;
            grayImage = rgb2gray(imgData);


            % 使用Ostu最大类间方差法，进行阈值分割（可根据实际情况调整阈值）
            threshold = graythresh(grayImage);

            app.LabelFGOstu.Text = threshold * 255 + "";

            binaryImage = imbinarize(grayImage, threshold);

            % 对二值图像进行后处理（去除小的区域）
            binaryImage = bwareaopen(binaryImage, 1000);

            % 创建彩色蒙版图像
            if app.FGreverse == false
                colorMask = ~cat(3, binaryImage, binaryImage, binaryImage);
            else
                colorMask = cat(3, binaryImage, binaryImage, binaryImage);
            end

            % 将彩色蒙版叠加到原始图片上
            segmentedResult = imgData;

            % 获取满足条件的像素索引
            indices = colorMask;

            % 将这些像素设置为白色
            segmentedResult(indices) = 255;

            binaryImage = cat(3, binaryImage, binaryImage, binaryImage);

            binaryImage = uint8(binaryImage) * 255;
            app.ImgFGbin.ImageSource = binaryImage;
            app.ImgFGres.ImageSource = segmentedResult;

        end

        % Value changed function: KnobFGt
        function KnobFGtValueChanged(app, event)
            threshold = app.KnobFGt.Value;

            imgData = app.ImgFGinput.ImageSource;
            grayImage = rgb2gray(imgData);

            app.LabelFGt.Text = round(threshold) + "";

            binaryImage = imbinarize(grayImage, threshold / 255);

            % 对二值图像进行后处理（去除小的区域）
            binaryImage = bwareaopen(binaryImage, 1000);

            % 创建彩色蒙版图像
            if app.FGreverse == false
                colorMask = ~cat(3, binaryImage, binaryImage, binaryImage);
            else
                colorMask = cat(3, binaryImage, binaryImage, binaryImage);
            end
            % 将彩色蒙版叠加到原始图片上
            segmentedResult = imgData;

            % 获取满足条件的像素索引
            indices = colorMask;

            % 将这些像素设置为白色
            segmentedResult(indices) = 255;

            binaryImage = cat(3, binaryImage, binaryImage, binaryImage);

            binaryImage = uint8(binaryImage) * 255;
            app.ImgFGbin.ImageSource = binaryImage;
            app.ImgFGres.ImageSource = segmentedResult;
        end

        % Callback function: Button, SpinnerFGk
        function SpinnerFGkValueChanged(app, event)
            k = app.SpinnerFGk.Value;
            % 转换为灰度图像
            imgData = app.ImgFGinput.ImageSource;


            binaryImage = clusterImage(app, imgData, k);
            % 创建彩色蒙版图像
            if app.FGreverse == false
                colorMask = ~cat(3, binaryImage, binaryImage, binaryImage);
            else
                colorMask = cat(3, binaryImage, binaryImage, binaryImage);
            end

            segmentedResult = imgData;

            % 获取满足条件的像素索引
            indices = colorMask;

            % 将这些像素设置为白色
            segmentedResult(indices) = 255;

            binaryImage = cat(3, binaryImage, binaryImage, binaryImage);

            binaryImage = uint8(binaryImage) * 255;
            app.ImgFGbin.ImageSource = binaryImage;
            app.ImgFGres.ImageSource = segmentedResult;

        end

        % Value changed function: CheckBoxFGreverse
        function CheckBoxFGreverseValueChanged(app, event)
            value = app.CheckBoxFGreverse.Value;
            app.FGreverse = value;
        end

        % Callback function
        function KnobFGhoughValueChanged(app, event)
            threshold = app.KnobFGhough.Value;

            imgData = app.ImgFGinput.ImageSource;


            app.LabelFGhough.Text = round(threshold) + "";

            binaryImage = houghSegmentation(app, imgData, threshold);

            % 创建彩色蒙版图像
            if app.FGreverse == false
                colorMask = ~cat(3, binaryImage, binaryImage, binaryImage);
            else
                colorMask = cat(3, binaryImage, binaryImage, binaryImage);
            end
            % 将彩色蒙版叠加到原始图片上
            segmentedResult = imgData;

            % 获取满足条件的像素索引
            indices = colorMask;

            % 将这些像素设置为白色
            segmentedResult(indices) = 255;

            binaryImage = cat(3, binaryImage, binaryImage, binaryImage);

            binaryImage = uint8(binaryImage) * 255;
            app.ImgFGbin.ImageSource = binaryImage;
            app.ImgFGres.ImageSource = segmentedResult;
        end

        % Button pushed function: Btnseed
        function BtnseedPushed(app, event)
            binaryImage =   interactiveRegionGrowing(app, app.ImgFGinput.ImageSource);
            imgData = app.ImgFGinput.ImageSource;
            % 创建彩色蒙版图像
            if app.FGreverse == false
                colorMask = ~cat(3, binaryImage, binaryImage, binaryImage);
            else
                colorMask = cat(3, binaryImage, binaryImage, binaryImage);
            end

            colorMask = ~cat(3, binaryImage, binaryImage, binaryImage);
            % 将彩色蒙版叠加到原始图片上
            segmentedResult = imgData;

            % 获取满足条件的像素索引
            indices = colorMask;
            % 将这些像素设置为白色

            indices(end) = 1;
            segmentedResult(indices) = 255;

            binaryImage = cat(3, binaryImage, binaryImage, binaryImage);

            binaryImage = uint8(binaryImage) * 255;
            app.ImgFGbin.ImageSource = binaryImage;
            app.ImgFGres.ImageSource = segmentedResult;
        end

        % Button pushed function: Btnactive
        function BtnactiveButtonPushed(app, event)
            imgData = app.ImgFGinput.ImageSource;
            I = rgb2gray(app.ImgFGinput.ImageSource);

            imshow(I);
            title('Original Image');


            r =  drawpolygon ;
            mask = createMask(r);

            imshow(mask)
            title('Initial Contour Location')


            binaryImage = activecontour(I,mask,1500,'edge');
            % 将逻辑图像转换为RGB图像
            rgbImage = cat(3, binaryImage, binaryImage, binaryImage);
            % 将RGB图像转换为uint8类型
            rgbImage = uint8(rgbImage * 255);


            app.ImgFGbin.ImageSource = rgbImage;


            % 创建彩色蒙版图像
            if app.FGreverse == false
                colorMask = ~cat(3, binaryImage, binaryImage, binaryImage);
            else
                colorMask = cat(3, binaryImage, binaryImage, binaryImage);
            end


            segmentedResult = imgData;

            % 获取满足条件的像素索引
            indices = colorMask;
            % 将这些像素设置为白色

            indices(end) = 1;
            segmentedResult(indices) = 255;

            binaryImage = cat(3, binaryImage, binaryImage, binaryImage);

            binaryImage = uint8(binaryImage) * 255;

            app.ImgFGres.ImageSource = segmentedResult;


        end

        % Button pushed function: LBPButton
        function LBPButtonPushed(app, event)
            % 进行LBP特征提取
            radius = app.SpinnerNFgVar_2.Value;
            neighbors = app.SpinnerNFgVar_3.Value;
            img = imread(app.Original.ImageSource);

            imgData = rgb2gray(img);
            [~, lbp_image] = lbp_histogram(app,imgData, radius, neighbors);
            
            % lbp_image_rgb = repmat(lbp_image, [1, 1, 3]);
            lbp_image_rgb = cat(3,lbp_image,lbp_image,lbp_image);
          

            % 设置图像源
            app.Image.ImageSource = uint8(lbp_image_rgb);
        end

        % Button pushed function: HOGButton
        function HOGButtonPushed(app, event)
            cell_size = app.SpinnerNFgVar_4.Value;
            block_size = app.SpinnerNFgVar_5.Value;
            bins = app.SpinnerNFgVar_6.Value;
            img = imread(app.Original.ImageSource);
            imgData = rgb2gray(img);
            imgData = double(imgData);
           % 调用HOG特征提取函数
            [~, hog_image] = hog_feature(app,imgData, cell_size, block_size, bins);
            
            % 将HOG特征图像显示到界面上
            % 将灰度图像转换为RGB格式
            hog_image_rgb = cat(3,hog_image,hog_image,hog_image);
            
            app.Image_3.ImageSource = uint8(hog_image_rgb);  % 转为uint8  

        end

        % Value changed function: KnobNFsigma_s
        function KnobNFsigma_sValueChanged(app, event)
            value = app.KnobNFsigma_s.Value;
            
        end

        % Image clicked function: Original
        function OriginalImageClicked(app, event)
             [filename, pathname] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'}, '选择图像文件');
            if isequal(filename, 0) || isequal(pathname, 0)
                return;
            end
            fullFilePath = fullfile(pathname, filename);
            app.Original.ImageSource = fullFilePath;
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            w = round(app.SliderNFomega.Value);
            simga_s = double(app.KnobNFsigma_s.Value);
            simga_r = double(app.KnobNFsigma_r.Value);

            app.ImgNFfilter_double.ImageSource =filter_double(app, app.ImgNFnoise.ImageSource, w, simga_s, simga_r);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Histogram = uitab(app.TabGroup);
            app.Histogram.Title = '直方图处理';
            app.UIAxesGrayHistogramHistgram = uiaxes(app.Histogram);
            title(app.UIAxesGrayHistogramHistgram, '灰度直方图')
            xlabel(app.UIAxesGrayHistogramHistgram, '灰度值')
            ylabel(app.UIAxesGrayHistogramHistgram, '频次')
            zlabel(app.UIAxesGrayHistogramHistgram, 'Z')
            app.UIAxesGrayHistogramHistgram.ButtonDownFcn = createCallbackFcn(app, @BtnEnhanceExpButtonPushed, true);
            app.ImgHistogramEqualization = uiimage(app.Histogram);
            app.BtnHistogramEqualization = uibutton(app.Histogram, 'push');
            app.BtnHistogramEqualization.ButtonPushedFcn = createCallbackFcn(app, @BtnHistogramEqualizationPushed, true);
            app.BtnHistogramEqualization.Text = '直方图均衡化';
            app.IImgHistogramInput = uiimage(app.Histogram);
            app.IImgHistogramInput.ImageClickedFcn = createCallbackFcn(app, @ImgHistogramInputOnClicked, true);
            app.Label = uilabel(app.Histogram);
            app.Label.Text = '原始图像';
            app.Label_2 = uilabel(app.Histogram);
            app.Label_2.Text = '直方图均衡化';
            app.Label_3 = uilabel(app.Histogram);
            app.Label_3.Text = '灰度图';
            app.ImgHistogramGray = uiimage(app.Histogram);
            app.Label_4 = uilabel(app.Histogram);
            app.Label_4.Text = '选择要显示灰度直方图的图像';
            app.DropDownHistogram = uidropdown(app.Histogram);
            app.DropDownHistogram.Items = {'原图', '均衡化'};
            app.DropDownHistogram.ValueChangedFcn = createCallbackFcn(app, @DropDownHistogramValueChanged, true);
            app.DropDownHistogram.Value = '原图';
            app.Enhance = uitab(app.TabGroup);
            app.Enhance.Title = '灰度图像的对比度增强';
            app.ImgEnhanceInput = uiimage(app.Enhance);
            app.ImgEnhanceInput.ImageClickedFcn = createCallbackFcn(app, @ImgEnhanceInputClicked, true);
            app.Label_5 = uilabel(app.Enhance);
            app.Label_5.Text = '原始图像';
            app.Label_6 = uilabel(app.Enhance);
            app.Label_6.Text = '灰度图';
            app.ImgEnhanceGray = uiimage(app.Enhance);
            app.ImgEnhanceLinear = uiimage(app.Enhance);
            app.Label_7 = uilabel(app.Enhance);
            app.Label_7.Text = '线性灰度级增强';
            app.tanSpinnerLabel = uilabel(app.Enhance);
            app.tanSpinnerLabel.Interpreter = 'latex';
            app.tanSpinnerLabel.Text = 'tanα = ';
            app.SpinnerEnhanceTan = uispinner(app.Enhance);
            app.SpinnerEnhanceTan.Step = 0.05;
            app.SpinnerEnhanceTan.Limits = [0 5];
            app.SpinnerEnhanceTan.ValueChangedFcn = createCallbackFcn(app, @SpinnerEnhanceTanValueChanged, true);
            app.SpinnerEnhanceTan.Value = 2;
            app.ImgEnhanceLog = uiimage(app.Enhance);
            app.ImgEnhanceExp = uiimage(app.Enhance);
            app.Label_8 = uilabel(app.Enhance);
            app.Label_8.Text = '非线性对数变换';
            app.Label_9 = uilabel(app.Enhance);
            app.Label_9.Text = '非线性指数变换';
            app.cLabel = uilabel(app.Enhance);
            app.cLabel.Interpreter = 'latex';
            app.cLabel.Text = 'c = ';
            app.SpinnerEnhanceLog = uispinner(app.Enhance);
            app.SpinnerEnhanceLog.Step = 2;
            app.SpinnerEnhanceLog.Limits = [1 200];
            app.SpinnerEnhanceLog.ValueChangedFcn = createCallbackFcn(app, @SpinnerEnhanceLogValueChanged, true);
            app.SpinnerEnhanceLog.Value = 46;
            app.gxytanalphacdotfxyLabel = uilabel(app.Enhance);
            app.gxytanalphacdotfxyLabel.Interpreter = 'latex';
            app.gxytanalphacdotfxyLabel.Text = '$$g(x, y) = tan\alpha \cdot f(x, y)  $$';
            app.gxyccdotlogfxy1Label = uilabel(app.Enhance);
            app.gxyccdotlogfxy1Label.Interpreter = 'latex';
            app.gxyccdotlogfxy1Label.Text = '$$g(x, y) = c \cdot log [f(x, y) + 1]$$';
            app.gxybccdotfxyalpha1Label = uilabel(app.Enhance);
            app.gxybccdotfxyalpha1Label.Interpreter = 'latex';
            app.gxybccdotfxyalpha1Label.Text = '$$g(x, y) = b ^ {  c \cdot [f(x, y) - \alpha]  } - 1$$';
            app.cLabel_2 = uilabel(app.Enhance);
            app.cLabel_2.Interpreter = 'latex';
            app.cLabel_2.Text = 'b = ';
            app.SpinnerEnhanceExpb = uispinner(app.Enhance);
            app.SpinnerEnhanceExpb.Step = 0.1;
            app.SpinnerEnhanceExpb.Limits = [0 10];
            app.SpinnerEnhanceExpb.Value = 2.818;
            app.cLabel_3 = uilabel(app.Enhance);
            app.cLabel_3.Interpreter = 'latex';
            app.cLabel_3.Text = 'c = ';
            app.SpinnerEnhanceExpc = uispinner(app.Enhance);
            app.SpinnerEnhanceExpc.Step = 0.01;
            app.SpinnerEnhanceExpc.Limits = [0 1];
            app.SpinnerEnhanceExpc.Value = 0.05;
            app.cLabel_4 = uilabel(app.Enhance);
            app.cLabel_4.Interpreter = 'latex';
            app.cLabel_4.Text = '$ \alpha $ = ';
            app.SpinnerEnhanceExpa = uispinner(app.Enhance);
            app.SpinnerEnhanceExpa.Step = 5;
            app.SpinnerEnhanceExpa.Limits = [0 255];
            app.SpinnerEnhanceExpa.Value = 5;
            app.BtnEnhanceExp = uibutton(app.Enhance, 'push');
            app.BtnEnhanceExp.ButtonPushedFcn = createCallbackFcn(app, @BtnEnhanceExpButtonPushed, true);
            app.BtnEnhanceExp.Text = '非线性指数变换';
            app.RotateScale = uitab(app.TabGroup);
            app.RotateScale.Title = '缩放旋转';
            app.ImgRSInput = uiimage(app.RotateScale);
            app.ImgRSInput.ImageClickedFcn = createCallbackFcn(app, @ImgRSInputImageClicked, true);
            app.Label_10 = uilabel(app.RotateScale);
            app.Label_10.Text = '原始图像';
            app.ImgRSScale = uiimage(app.RotateScale);
            app.Label_11 = uilabel(app.RotateScale);
            app.Label_11.Text = '缩放图像';
            app.LabelRSInputSize = uilabel(app.RotateScale);
            app.LabelRSInputSize.Interpreter = 'latex';
            app.LabelRSInputSize.Text = '图片尺寸：$ 0000 \times 0000 $';
            app.LabelRSScaleSize = uilabel(app.RotateScale);
            app.LabelRSScaleSize.Interpreter = 'latex';
            app.LabelRSScaleSize.Text = '缩放后尺寸：$ 0000 \times 0000 $';
            app.LabelRSScalek = uilabel(app.RotateScale);
            app.LabelRSScalek.Interpreter = 'latex';
            app.LabelRSScalek.Text = '缩放倍数：$0.00 \times 0.00  $';
            app.k_xSliderLabel_2 = uilabel(app.RotateScale);
            app.k_xSliderLabel_2.Text = 'k_x';
            app.SliderRSk_x = uislider(app.RotateScale);
            app.SliderRSk_x.Limits = [0.03 5.03];
            app.SliderRSk_x.ValueChangedFcn = createCallbackFcn(app, @SliderRSValueChanged, true);
            app.SliderRSk_x.Value = 1;
            app.k_yLabel = uilabel(app.RotateScale);
            app.k_yLabel.Text = 'k_y';
            app.SliderRSk_y = uislider(app.RotateScale);
            app.SliderRSk_y.Limits = [0.03 5.03];
            app.SliderRSk_y.ValueChangedFcn = createCallbackFcn(app, @SliderRSValueChanged, true);
            app.SliderRSk_y.Value = 1;
            app.ImgRSrotate = uiimage(app.RotateScale);
            app.Label_12 = uilabel(app.RotateScale);
            app.Label_12.Text = '旋转图像';
            app.Label_14 = uilabel(app.RotateScale);
            app.Label_14.Text = '旋转';
            app.KnobRSrotate = uiknob(app.RotateScale, 'continuous');
            app.KnobRSrotate.Limits = [-180 180];
            app.KnobRSrotate.ValueChangedFcn = createCallbackFcn(app, @KnobRSrotateValueChanged, true);
            app.ImgRSfit = uiimage(app.RotateScale);
            app.Label_13 = uilabel(app.RotateScale);
            app.Label_13.Text = '适配图';
            app.NoiseFilter = uitab(app.TabGroup);
            app.NoiseFilter.Title = '噪声滤波';
            app.ImgNFInput = uiimage(app.NoiseFilter);
            app.ImgNFInput.ImageClickedFcn = createCallbackFcn(app, @ImgNFInputImageClicked, true);
            app.Label_15 = uilabel(app.NoiseFilter);
            app.Label_15.Text = '原始图像';
            app.TabGroup2 = uitabgroup(app.NoiseFilter);
            app.Tab = uitab(app.TabGroup2);
            app.Tab.Title = '椒盐噪声';
            app.Label_17 = uilabel(app.Tab);
            app.Label_17.Text = '噪声密度';
            app.SliderNFsaltD = uislider(app.Tab);
            app.SliderNFsaltD.Limits = [0 1];
            app.SliderNFsaltD.ValueChangedFcn = createCallbackFcn(app, @SliderNFsaltDValueChanged, true);
            app.Tab_2 = uitab(app.TabGroup2);
            app.Tab_2.Title = '高斯噪声';
            app.Label_18 = uilabel(app.Tab_2);
            app.Label_18.Text = '均值';
            app.SliderNFgm = uislider(app.Tab_2);
            app.SliderNFgm.Limits = [-1 1];
            app.Label_19 = uilabel(app.Tab_2);
            app.Label_19.Text = '方差';
            app.SpinnerNFgVar = uispinner(app.Tab_2);
            app.SpinnerNFgVar.Step = 0.01;
            app.SpinnerNFgVar.Limits = [0 2];
            app.BtnNFg = uibutton(app.Tab_2, 'push');
            app.BtnNFg.ButtonPushedFcn = createCallbackFcn(app, @BtnNFgButtonPushed, true);
            app.BtnNFg.Text = '显示高斯噪声';
            app.Label_16 = uilabel(app.NoiseFilter);
            app.Label_16.Text = '噪声图像 ';
            app.ImgNFnoise = uiimage(app.NoiseFilter);
            app.Panel = uipanel(app.NoiseFilter);
            app.Panel.Title = '空间域滤波';
            app.ImgNFfilter_double = uiimage(app.Panel);
            app.ImgNFfilter_double.ImageClickedFcn = createCallbackFcn(app, @ImgNFfilter_doubleImageClicked, true);
            app.Label_21 = uilabel(app.Panel);
            app.Label_21.Text = '双边滤波';
            app.omegaLabel = uilabel(app.Panel);
            app.omegaLabel.Interpreter = 'latex';
            app.omegaLabel.Text = '$\omega$';
            app.SliderNFomega = uislider(app.Panel);
            app.SliderNFomega.Limits = [1 30];
            app.SliderNFomega.Tooltip = {' 表示双边滤波器的窗口大小'};
            app.SliderNFomega.Value = 10;
            app.sigma_sKnobLabel = uilabel(app.Panel);
            app.sigma_sKnobLabel.Interpreter = 'latex';
            app.sigma_sKnobLabel.Text = '$\sigma_s$';
            app.KnobNFsigma_s = uiknob(app.Panel, 'continuous');
            app.KnobNFsigma_s.Limits = [2 10];
            app.KnobNFsigma_s.ValueChangedFcn = createCallbackFcn(app, @KnobNFsigma_sValueChanged, true);
            app.KnobNFsigma_s.Tooltip = {'决定滤波窗口中的像素个数'};
            app.KnobNFsigma_s.Value = 5;
            app.sigma_rKnobLabel = uilabel(app.Panel);
            app.sigma_rKnobLabel.Interpreter = 'latex';
            app.sigma_rKnobLabel.Text = '$\sigma_r$';
            app.KnobNFsigma_r = uiknob(app.Panel, 'continuous');
            app.KnobNFsigma_r.Limits = [0 1];
            app.KnobNFsigma_r.Tooltip = {'控制灰度差值多大的点能影响中心点的像素值'};
            app.KnobNFsigma_r.Value = 0.25;
            app.omegasigma_ssigma_rLabel = uilabel(app.Panel);
            app.omegasigma_ssigma_rLabel.Interpreter = 'latex';
            app.omegasigma_ssigma_rLabel.Text = '$ \omega, \sigma_s, \sigma_r  $  取值越大，图像平滑作用越强';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '双边滤波';
            app.Panel_3 = uipanel(app.NoiseFilter);
            app.Panel_3.Title = '其他滤波方法';
            app.ImgNFfiltermohu = uiimage(app.Panel_3);
            app.ImgNFfiltermohu.ImageClickedFcn = createCallbackFcn(app, @NKnobNFmohuValueChanged, true);
            app.Label_23 = uilabel(app.Panel_3);
            app.Label_23.Text = '基于模糊技术的平滑滤波';
            app.NKnobLabel = uilabel(app.Panel_3);
            app.NKnobLabel.Interpreter = 'latex';
            app.NKnobLabel.Text = '邻域内点的数目 $N$';
            app.NKnobNFmohu = uiknob(app.Panel_3, 'discrete');
            app.NKnobNFmohu.Items = {'3', '5', '7', '9'};
            app.NKnobNFmohu.ValueChangedFcn = createCallbackFcn(app, @NKnobNFmohuValueChanged, true);
            app.NKnobNFmohu.Value = '3';
            app.Edge = uitab(app.TabGroup);
            app.Edge.Title = '边缘检测';
            app.imgEdgeinput = uiimage(app.Edge);
            app.imgEdgeinput.ImageClickedFcn = createCallbackFcn(app, @imgEdgeinputImageClicked, true);
            app.Label_24 = uilabel(app.Edge);
            app.Label_24.Text = '原始图像';
            app.Label_25 = uilabel(app.Edge);
            app.Label_25.Text = '二分阈值';
            app.SliderEdge_T = uislider(app.Edge);
            app.SliderEdge_T.Limits = [0 50];
            app.SliderEdge_T.ValueChangedFcn = createCallbackFcn(app, @SliderEdge_TValueChanged, true);
            app.SliderEdge_T.Value = 20;
            app.imgEdge_robert = uiimage(app.Edge);
            app.imgEdge_prewitt = uiimage(app.Edge);
            app.imgEdge_laplacian = uiimage(app.Edge);
            app.imgEdge_sobel = uiimage(app.Edge);
            app.RobertLabel = uilabel(app.Edge);
            app.RobertLabel.Text = 'Robert算子';
            app.SobelLabel = uilabel(app.Edge);
            app.SobelLabel.Text = 'Sobel算子';
            app.PrewittLabel = uilabel(app.Edge);
            app.PrewittLabel.Text = 'Prewitt算子';
            app.LaplacianLabel = uilabel(app.Edge);
            app.LaplacianLabel.Text = 'Laplacian算子';
            app.ImgEdge_gray = uiimage(app.Edge);
            app.Label_26 = uilabel(app.Edge);
            app.Label_26.Text = '灰度图';
            app.FG = uitab(app.TabGroup);
            app.FG.Title = '图像分割';
            app.ImgFGinput = uiimage(app.FG);
            app.ImgFGinput.ImageClickedFcn = createCallbackFcn(app, @ImgFGinputImageClicked, true);
            app.Label_27 = uilabel(app.FG);
            app.Label_27.Text = '背景/背景+前景';
            app.ImgFGbin = uiimage(app.FG);
            app.Label_28 = uilabel(app.FG);
            app.Label_28.Text = '分割二值化蒙版';
            app.TabGroup3 = uitabgroup(app.FG);
            app.Tab_3 = uitab(app.TabGroup3);
            app.Tab_3.Title = '最大类间方差法';
            app.LabelFGOstu = uilabel(app.Tab_3);
            app.LabelFGOstu.Text = '000.000';
            app.OtsuLabel = uilabel(app.Tab_3);
            app.OtsuLabel.Text = '采用Otsu方法计算的图像全局最佳阈值为：';
            app.BtnFGOstu = uibutton(app.Tab_3, 'push');
            app.BtnFGOstu.ButtonPushedFcn = createCallbackFcn(app, @BtnFGOstuButtonPushed, true);
            app.BtnFGOstu.Text = 'Ostu最大类间方差法进行阈值分隔';
            app.Tab_4 = uitab(app.TabGroup3);
            app.Tab_4.Title = '手动阈值分割';
            app.ThresholdLabel = uilabel(app.Tab_4);
            app.ThresholdLabel.Text = 'Threshold';
            app.KnobFGt = uiknob(app.Tab_4, 'continuous');
            app.KnobFGt.Limits = [0 255];
            app.KnobFGt.ValueChangedFcn = createCallbackFcn(app, @KnobFGtValueChanged, true);
            app.LabelFGt = uilabel(app.Tab_4);
            app.LabelFGt.Text = '000.000';
            app.Label_31 = uilabel(app.Tab_4);
            app.Label_31.Text = '当前分割阈值：';
            app.kTab = uitab(app.TabGroup3);
            app.kTab.Title = 'k聚类';
            app.KSpinnerLabel = uilabel(app.kTab);
            app.KSpinnerLabel.Text = 'K';
            app.SpinnerFGk = uispinner(app.kTab);
            app.SpinnerFGk.Limits = [2 5];
            app.SpinnerFGk.ValueChangedFcn = createCallbackFcn(app, @SpinnerFGkValueChanged, true);
            app.SpinnerFGk.Value = 2;
            app.Button = uibutton(app.kTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @SpinnerFGkValueChanged, true);
            app.Button.Text = '聚类分割';
            app.Tab_5 = uitab(app.TabGroup3);
            app.Tab_5.Title = '区域生长法';
            app.Btnseed = uibutton(app.Tab_5, 'push');
            app.Btnseed.ButtonPushedFcn = createCallbackFcn(app, @BtnseedPushed, true);
            app.Btnseed.Text = '区域生长法图像分割';
            app.ThresholdLabel_2 = uilabel(app.Tab_5);
            app.ThresholdLabel_2.Text = 'Threshold';
            app.KnobFGseed = uiknob(app.Tab_5, 'continuous');
            app.KnobFGseed.Limits = [0 255];
            app.Tab_6 = uitab(app.TabGroup3);
            app.Tab_6.Title = '主动轮廓';
            app.Btnactive = uibutton(app.Tab_6, 'push');
            app.Btnactive.ButtonPushedFcn = createCallbackFcn(app, @BtnactiveButtonPushed, true);
            app.Btnactive.Text = '主动轮廓 (snake) 区域增长方法';
            app.ImgFGtarget = uiimage(app.FG);
            app.ImgFGtarget.ImageClickedFcn = createCallbackFcn(app, @ImgFGtargetImageClicked, true);
            app.Label_29 = uilabel(app.FG);
            app.Label_29.Text = '目标图像';
            app.ImgFGres = uiimage(app.FG);
            app.Label_30 = uilabel(app.FG);
            app.Label_30.Text = '分割结果图像';
            app.CheckBoxFGreverse = uicheckbox(app.FG);
            app.CheckBoxFGreverse.ValueChangedFcn = createCallbackFcn(app, @CheckBoxFGreverseValueChanged, true);
            app.CheckBoxFGreverse.Text = '蒙版取反';
            app.Tab_7 = uitab(app.TabGroup);
            app.Tab_7.Title = '特征提取';
            app.LBPButton = uibutton(app.Tab_7, 'push');
            app.LBPButton.ButtonPushedFcn = createCallbackFcn(app, @LBPButtonPushed, true);
            app.LBPButton.Text = 'LBP特征提取';
            app.Label_32 = uilabel(app.Tab_7);
            app.Label_32.Text = '半径';
            app.SpinnerNFgVar_2 = uispinner(app.Tab_7);
            app.SpinnerNFgVar_2.Limits = [1 2];
            app.SpinnerNFgVar_2.Value = 1;
            app.Label_33 = uilabel(app.Tab_7);
            app.Label_33.Text = '邻居数量';
            app.SpinnerNFgVar_3 = uispinner(app.Tab_7);
            app.SpinnerNFgVar_3.Step = 8;
            app.SpinnerNFgVar_3.Limits = [8 16];
            app.SpinnerNFgVar_3.Value = 8;
            app.HOGButton = uibutton(app.Tab_7, 'push');
            app.HOGButton.ButtonPushedFcn = createCallbackFcn(app, @HOGButtonPushed, true);
            app.HOGButton.Text = 'HOG特征提取';
            app.Label_34 = uilabel(app.Tab_7);
            app.Label_34.Text = 'bolck_size';
            app.SpinnerNFgVar_4 = uispinner(app.Tab_7);
            app.SpinnerNFgVar_4.Limits = [1 2];
            app.SpinnerNFgVar_4.Value = 2;
            app.Label_35 = uilabel(app.Tab_7);
            app.Label_35.Text = 'cell_size';
            app.SpinnerNFgVar_5 = uispinner(app.Tab_7);
            app.SpinnerNFgVar_5.Step = 2;
            app.SpinnerNFgVar_5.Limits = [6 8];
            app.SpinnerNFgVar_5.Value = 6;
            app.Label_36 = uilabel(app.Tab_7);
            app.Label_36.Text = 'bins';
            app.SpinnerNFgVar_6 = uispinner(app.Tab_7);
            app.SpinnerNFgVar_6.Limits = [4 16];
            app.SpinnerNFgVar_6.Value = 9;
            app.Original = uiimage(app.Tab_7);
            app.Original.ImageClickedFcn = createCallbackFcn(app, @OriginalImageClicked, true);
            app.Original.ImageSource = fullfile(pathToMLAPP, '1.jpg');
            app.Image = uiimage(app.Tab_7);
            app.Image_3 = uiimage(app.Tab_7);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_view

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end